classdef audio3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea            matlab.ui.control.TextArea
        Label_8             matlab.ui.control.Label
        EditField_2         matlab.ui.control.EditField
        Label_6             matlab.ui.control.Label
        ButtonSave          matlab.ui.control.Button
        Buttonplay2         matlab.ui.control.Button
        EditFieldpath       matlab.ui.control.EditField
        fileEditFieldLabel  matlab.ui.control.Label
        Slider16k           matlab.ui.control.Slider
        kSlider_5Label      matlab.ui.control.Label
        Slider8k            matlab.ui.control.Slider
        kSlider_4Label      matlab.ui.control.Label
        Slider4k            matlab.ui.control.Slider
        kSlider_3Label      matlab.ui.control.Label
        Slider2k            matlab.ui.control.Slider
        kSlider_2Label      matlab.ui.control.Label
        Slider1k            matlab.ui.control.Slider
        kSliderLabel        matlab.ui.control.Label
        Slider500           matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        Slider250           matlab.ui.control.Slider
        Label_4             matlab.ui.control.Label
        Slider125           matlab.ui.control.Slider
        Label_3             matlab.ui.control.Label
        Slider62            matlab.ui.control.Slider
        Label_2             matlab.ui.control.Label
        Slider31            matlab.ui.control.Slider
        Label               matlab.ui.control.Label
        ButtonRun           matlab.ui.control.Button
        ButtonStop          matlab.ui.control.Button
        ButtonPlay          matlab.ui.control.Button
        ButtonLoad          matlab.ui.control.Button
        UIAxesAfter         matlab.ui.control.UIAxes
        UIAxesBefore        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        y double % 原双声道音频
        yn double % 均衡后双声道音频
        fs double
        playerold audioplayer
        playernew audioplayer
        y0 double % 原双声道混合音频
        y1 double % 均衡后混合音频
        x double % 频谱频率
        Y0 double % 原双声道混合频谱
        T double
        P double
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)
            %加载音乐文件及加载频谱
            [file,path]=uigetfile({'*.mp3';'*.wav'},'select a mp3 or wav file');
            if isequal(file,0)
                app.EditFieldpath.Value ='No file selected (do not change the file name).mp3';
            else
                app.EditFieldpath.Value = fullfile(path,file);
            end
            app.EditField_2.Value='Loading';
            pause(1);
            %loading audio files
            [app.y,app.fs]=audioread(app.EditFieldpath.Value);
            app.y0=(app.y(:,1)+app.y(:,2))/2;
            app.T=length(app.y0)/app.fs;
            app.Y0=fft(app.y0);
            app.playerold=audioplayer(app.y,app.fs);


            %频谱分析
            app.x=0:app.fs/length(app.y0):(length(app.y0)-1)*app.fs/(length(app.y0));
            %plot(app.UIAxesBefore,app.x,abs(app.Y0));
            app.EditField_2.Value='Loading completed';
        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            set(app.UIAxesBefore,'Ylim',[0,2]);
            app.P=0;
            app.EditField_2.Value='Playing original sound';
            tic;
            play(app.playerold);
            while toc<app.T
                if app.P
                    app.EditField_2.Value='播放停止';
                    break;
                end
                f=app.y0(floor(toc*app.fs)-2047:floor(toc*app.fs));
                Y=fft(f,2048);
                H=abs(Y(1:end/2))*2/2048;
                a1=H(1);a2=sum(H(2:3));a3=sum(H(4:5));a4=sum(H(6:11));a5=sum(H(12:23));
                a6=sum(H(24:46));a7=sum(H(47:93));a8=sum(H(94:186));a9=sum(H(187:372));a10=sum(H(373:end));
                a=[a1 a2 a3 a4 a5 a6 a7 a8 a9 a10];
                bar(app.UIAxesBefore,a);
                drawnow;
            end
            
        end

        % Button pushed function: ButtonRun
        function ButtonRunPushed(app, event)
            %This function is used for equalizing audio
            app.EditField_2.Value='Equalizing';
            pause(2);
            A1=exp(app.Slider31.Value/20);
            A2=exp(app.Slider62.Value/20);
            A3=exp(app.Slider125.Value/20);
            A4=exp(app.Slider250.Value/20);
            A5=exp(app.Slider500.Value/20);
            A6=exp(app.Slider1k.Value/20);
            A7=exp(app.Slider2k.Value/20);
            A8=exp(app.Slider4k.Value/20);
            A9=exp(app.Slider8k.Value/20);
            A10=exp(app.Slider16k.Value/20);
            Y=fft(app.y);
            n1=floor(31/app.fs*length(app.y0));n11=length(Y)-n1;
            n2=floor(62/app.fs*length(app.y0));n12=length(Y)-n2;
            n3=floor(125/app.fs*length(app.y0));n13=length(Y)-n3;
            n4=floor(250/app.fs*length(app.y0));n14=length(Y)-n4;
            n5=floor(500/app.fs*length(app.y0));n15=length(Y)-n5;
            n6=floor(1000/app.fs*length(app.y0));n16=length(Y)-n6;
            n7=floor(2000/app.fs*length(app.y0));n17=length(Y)-n7;
            n8=floor(4000/app.fs*length(app.y0));n18=length(Y)-n8;
            n9=floor(8000/app.fs*length(app.y0));n19=length(Y)-n9;
            
            Yn1=A1*Y(1:n1,:);Yn11=A1*Y(n11:end,:);
            Yn2=A2*Y(n1+1:n2,:);Yn12=A2*Y(n12:n11-1,:);
            Yn3=A3*Y(n2+1:n3,:);Yn13=A3*Y(n13:n12-1,:);
            Yn4=A4*Y(n3+1:n4,:);Yn14=A4*Y(n14:n13-1,:);
            Yn5=A5*Y(n4+1:n5,:);Yn15=A5*Y(n15:n14-1,:);
            Yn6=A6*Y(n5+1:n6,:);Yn16=A6*Y(n16:n15-1,:);
            Yn7=A7*Y(n6+1:n7,:);Yn17=A7*Y(n17:n16-1,:);
            Yn8=A8*Y(n7+1:n8,:);Yn18=A8*Y(n18:n17-1,:);
            Yn9=A9*Y(n8+1:n9,:);Yn19=A9*Y(n19:n18-1,:);
            Yn10=A10*Y(n9+1:n19-1,:);
            Yn=[Yn1;Yn2;Yn3;Yn4;Yn5;Yn6;Yn7;Yn8;Yn9;Yn10;Yn19;Yn18;Yn17;Yn16;Yn15;Yn14;Yn13;Yn12;Yn11];
            app.yn=real(ifft(Yn));
            app.y1=(app.yn(:,1)+app.yn(:,2))/2;
            YN=(Yn(:,1)+Yn(:,2))/2;
            %plot(app.UIAxesBefore,app.x,abs(app.Y0));drawnow;
            %plot(app.UIAxesAfter,app.x,abs(YN));drawnow;
            app.EditField_2.Value='Equalizing completed';
        end

        % Button pushed function: Buttonplay2
        function Buttonplay2Pushed(app, event)
            app.P=0;
            stop(app.playerold);
            app.EditField_2.Value='播放均衡后音频';
            app.playernew=audioplayer(app.yn,app.fs);
            set(app.UIAxesBefore,'Ylim',[0,2]);
            set(app.UIAxesAfter,'Ylim',[0,2]);
            tic;
            play(app.playernew);
            while toc<app.T
                if app.P
                    app.EditField_2.Value='播放停止';
                    break;
                end
                t=toc;
                f1=app.y0(floor(t*app.fs)-2047:floor(t*app.fs));
                f2=app.y1(floor(t*app.fs)-2047:floor(t*app.fs));
                f=[f1,f2];
                Y=fft(f,2048);
                H=abs(Y(1:end/2,:))*2/2048;
                a1=[H(1,1);H(1,2)];a2=[sum(H(2:3,1));sum(H(2:3,2))];a3=[sum(H(4:5,1));sum(H(4:5,2))];
                a4=[sum(H(6:11,1));sum(H(6:11,2))];a5=[sum(H(12:23,1));sum(H(12:23,2))];
                a6=[sum(H(24:46,1));sum(H(24:46,2))];a7=[sum(H(47:93,1));sum(H(47:93,2))];
                a8=[sum(H(94:186,1));sum(H(94:186,2))];a9=[sum(H(187:372,1));sum(H(187:372,2))];
                a10=[sum(H(373:end,1));sum(H(373:end,2))];
                a=[a1 a2 a3 a4 a5 a6 a7 a8 a9 a10];
                bar(app.UIAxesBefore,a(1,:));
                drawnow;
                bar(app.UIAxesAfter,a(2,:),'g');
                drawnow;
            end
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            if isempty(app.playerold)==0
                stop(app.playerold);app.P=1;
                app.EditField_2.Value='播放停止';
            end
            if isempty(app.playernew)==0
                stop(app.playernew);app.P=1;
                app.EditField_2.Value='播放停止';
            end
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)
            [file,path]=uiputfile('*.wav');
            app.EditField_2.Value='保存中';
            pause(1);
            filename=fullfile(path,file);
            audiowrite(filename,app.yn,app.fs);
            app.EditField_2.Value='保存完成';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '音乐均衡器';
            app.UIAxesBefore = uiaxes(app.UIFigure);
            title(app.UIAxesBefore, '原音')
            app.UIAxesAfter = uiaxes(app.UIFigure);
            title(app.UIAxesAfter, '均衡后')
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = '加载音乐';
            app.ButtonPlay = uibutton(app.UIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Text = '播放原音';
            app.ButtonStop = uibutton(app.UIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Text = '停止';
            app.ButtonRun = uibutton(app.UIFigure, 'push');
            app.ButtonRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunPushed, true);
            app.ButtonRun.Text = '均衡';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '31';
            app.Slider31 = uislider(app.UIFigure);
            app.Slider31.Limits = [-30 30];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '62';
            app.Slider62 = uislider(app.UIFigure);
            app.Slider62.Limits = [-30 30];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '125';
            app.Slider125 = uislider(app.UIFigure);
            app.Slider125.Limits = [-30 30];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '250';
            app.Slider250 = uislider(app.UIFigure);
            app.Slider250.Limits = [-30 30];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '500';
            app.Slider500 = uislider(app.UIFigure);
            app.Slider500.Limits = [-30 30];
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = '1k';
            app.Slider1k = uislider(app.UIFigure);
            app.Slider1k.Limits = [-30 30];
            app.kSlider_2Label = uilabel(app.UIFigure);
            app.kSlider_2Label.Text = '2k';
            app.Slider2k = uislider(app.UIFigure);
            app.Slider2k.Limits = [-30 30];
            app.kSlider_3Label = uilabel(app.UIFigure);
            app.kSlider_3Label.Text = '4k';
            app.Slider4k = uislider(app.UIFigure);
            app.Slider4k.Limits = [-30 30];
            app.kSlider_4Label = uilabel(app.UIFigure);
            app.kSlider_4Label.Text = '8k';
            app.Slider8k = uislider(app.UIFigure);
            app.Slider8k.Limits = [-30 30];
            app.kSlider_5Label = uilabel(app.UIFigure);
            app.kSlider_5Label.Text = '16k';
            app.Slider16k = uislider(app.UIFigure);
            app.Slider16k.Limits = [-30 30];
            app.fileEditFieldLabel = uilabel(app.UIFigure);
            app.fileEditFieldLabel.Text = 'file';
            app.EditFieldpath = uieditfield(app.UIFigure, 'text');
            app.EditFieldpath.Editable = 'off';
            app.Buttonplay2 = uibutton(app.UIFigure, 'push');
            app.Buttonplay2.ButtonPushedFcn = createCallbackFcn(app, @Buttonplay2Pushed, true);
            app.Buttonplay2.Text = '播放均衡后';
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Text = '保存';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '注意';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'在加载音乐和均衡时请预留足够内存'; ''; '仅支持采样率44.1kHz的mp3、wav文件'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end